classdef appMonitorLicorEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrometSiteInstrumentsUIFigure  matlab.ui.Figure
        LICORECMonitorLabel  matlab.ui.control.Label
        UpdateAllButton      matlab.ui.control.Button
        LastdataupdatePanel  matlab.ui.container.Panel
        LastUpdateLabel      matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Site_1               matlab.ui.container.Tab
    end

    % Revisions
    %
    % Jan 21, 2024 (Zoran)
    %   - found bug causing to have min values set to 0 when an element of the structure was set to [];
    %     The problem is with get_stats_field function. For the input:
    %      x(1).a=1;x(2).a=2;x(3).a=[];
    %     it returns:
    %       >> get_stats_field(x,'a')'  
    %       >>   1     2     0
    %     instead of 
    %       >> get_stats_field_fast(x,'a')'
    %       >>  1     2   NaN
    %      Replaced with get_stats_field_fast.
    % Jan 8, 2024 (Zoran)
    %   - found a bug. I had:
    %       exist('p:\database','dir')
    %     instead of:
    %      ~exist('p:\database','dir')
    %
    properties (Access = private)
        aVariable           % Description
        timerMain           % main timer object that initiates data collection
        siteInfo            % data structure with site names and IP addresses (loaded from a yml file)
        siteData            % data structure with all data for the current 30-min period
        sampleNum = 0;      % current data point being acquired
        tvLastSample = 0;   % datenum of the last data point  
        samplingPeriodSec   % sampling period
        outputFileNameHF = "monitorSiteLI7x00_HFdata.%s.mat";      %
        outputPathHF      % High frequency data path name
        outputFileNameHH = "monitorSiteLI7x00_hhour.%s.mat";      %
        outputPathHH      % Half hour summaries path name
        outputPathDB      % output path for the data base
        delayCalling = 20 % if running tests on my PC offset the timer by 20 seconds to avoid 
                          % overlapping the calls between my PC and the main server at vinimet
    end
    
    methods (Access = private)
        
        function results = downloadData(app,siteName,siteAddress,sitePortNum)
            arg_default('sitePortNum',7200);
            try
                clean_site_data(app,siteName);
                [dataOut,cError,allData] = fr_read_TCP_LI7x00_data(siteAddress,sitePortNum,5);
                app.siteData(app.sampleNum).(siteName) = dataOut; 
                ledColor = [0 1 0];
return                
                if isempty(cError)
                    %app.(sprintf('%s_LastError',siteName)).Text = "OK!";
                    app.(sprintf('%s_Diag',siteName)).Text              = sprintf('%8d    ',dataOut.Diag);
                        if isempty(dataOut.flowDrive) && dataOut.Diag == 255
                            % test if IRGA = 7500 (no flowDrive) and Diag = 255
                            % and if it is set the warning light on
                            app.(sprintf('%s_Diag',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];                           
                        end
                    app.(sprintf('%s_FlowLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.flowRate);
                        if dataOut.flowRate < 13 
                            app.(sprintf('%s_FlowLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];  
                        end
                    app.(sprintf('%s_FlowDriveLabel',siteName)).Text    = sprintf('%6.2f    ',dataOut.flowDrive);
                        if dataOut.flowDrive > 90 
                            app.(sprintf('%s_FlowDriveLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];  
                        end
                    app.(sprintf('%s_TinLabel',siteName)).Text          = sprintf('%6.2f    ',dataOut.tempIn);
                    app.(sprintf('%s_ToutLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.tempOut);
                        if ~isempty(dataOut.tempOut) && abs(dataOut.tempOut-dataOut.tempIn) > 3
                            app.(sprintf('%s_TinLabel',siteName)).FontColor = [1 0 0];
                            app.(sprintf('%s_ToutLabel',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];
                        end
                    app.(sprintf('%s_PheadLabel',siteName)).Text        = sprintf('%6.2f    ',dataOut.Phead);
                    app.(sprintf('%s_PairLabel',siteName)).Text         = sprintf('%6.2f    ',dataOut.Pair);
                    app.(sprintf('%s_CO2Label',siteName)).Text          = sprintf('%6.2f    ',dataOut.CO2);
                    app.(sprintf('%s_H2OLabel',siteName)).Text          = sprintf('%6.2f    ',dataOut.H2O);
                    app.(sprintf('%s_Diag7700Label',siteName)).Text     = sprintf('%6.2f    ',dataOut.diag7700);
                    app.(sprintf('%s_SS7700Label',siteName)).Text       = sprintf('%6.2f    ',dataOut.signalStrength7700);
                         if dataOut.signalStrength7700 < 20
                            app.(sprintf('%s_SS7700Label',siteName)).FontColor = [1 0 0];
                            ledColor = [1 1 0];
                         end                   
                    app.(sprintf('%s_CH4Label',siteName)).Text = sprintf('%6.2f    ',dataOut.CH4);                    
                    app.(sprintf('%s_Lamp',siteName)).Color = ledColor;
                    app.(sprintf('%s_LastError',siteName)).Text = '';       % clear last error
                    results = 0;              
                else
                    app.(sprintf('%s_Lamp',siteName)).Color = [1 0 0];
                    app.(sprintf('%s_LastError',siteName)).Text = [cError allData];                    
                end              

            catch 
                app.(sprintf('%s_Lamp',siteName)).Color = [1 0 0];
                fprintf('*ERROR for %s site.\n',siteName);
                %disp(dataOut)
            end
            drawnow
        end
        
        function results = clean_site_data(app,siteName)
            try
                app.(sprintf('%s_Lamp',siteName)).Color = [0.8 0.8 0.8];
                app.(sprintf('%s_Diag',siteName)).Text = '';
                app.(sprintf('%s_FlowLabel',siteName)).Text = '';
                app.(sprintf('%s_FlowDriveLabel',siteName)).Text = '';
                app.(sprintf('%s_TinLabel',siteName)).Text = '';
                app.(sprintf('%s_ToutLabel',siteName)).Text = '';
                app.(sprintf('%s_PheadLabel',siteName)).Text = '';
                app.(sprintf('%s_PairLabel',siteName)).Text = '';
                app.(sprintf('%s_CO2Label',siteName)).Text = '';
                app.(sprintf('%s_H2OLabel',siteName)).Text = '';
                app.(sprintf('%s_Diag7700Label',siteName)).Text = '';
                app.(sprintf('%s_SS7700Label',siteName)).Text = '';
                app.(sprintf('%s_CH4Label',siteName)).Text = '';   
                                        
                % return font colors to black
                app.(sprintf('%s_Diag',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_FlowLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_FlowDriveLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_TinLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_ToutLabel',siteName)).FontColor = [0 0 0];
                app.(sprintf('%s_SS7700Label',siteName)).FontColor = [0 0 0];
                results = 0;    
            catch
                results = 1;
            end
            drawnow;
        end
        
        function mainTimerCallback(app, ~, event)
            UpdateAllButtonPushed(app,event)
        end
        
        % ==================================================
        % halfHourEvent
        %
        % Calculates stats, stores HF and HH data
        %===================================================
        function halfHourEvent(app,currentHHour)
return           
            % Save current data into dataHF
            dataHF = app.siteData;
            
            % save high frequency data using currentHHour (need an
            % end-of-period time stamp)
            fileFolder = fullfile(app.outputPathHF,datestr(currentHHour,'yyyymmdd'));
            if ~exist(fileFolder,"dir")
                mkdir(fileFolder)                     
            end
            fileName = sprintf(app.outputFileNameHF,datestr(currentHHour,'yyyymmddHHMM'));
            filePathHF = fullfile(fileFolder,fileName);
            save(filePathHF,'dataHF')
            
            % clean data buffer
            app.siteData = [];
            app.sampleNum = 0;            
            
            try
                %% calculate hhourly stats            
                % cycle through all sites
                strAllSiteNames = fieldnames(dataHF);             
                for cntSiteName = 1:length(strAllSiteNames)
                    strSiteName = char(strAllSiteNames{cntSiteName});
                    % pick only site names, skip TimeVector
                    if ~strcmpi(strSiteName,"TimeVector")     
                        % now get all variables that need averaging
                        strAllVariables = fieldnames(dataHF(1).(strSiteName));
                        for cntVarName = 1:length(strAllVariables)
                            strVarName = char(strAllVariables{cntVarName});
                            dataIn = get_stats_field_fast(dataHF,[strSiteName '.' strVarName]);                        
                            if ~strcmpi(strVarName,"TimeVector")
                                tmpStats.(strSiteName).(strVarName).avg = mean(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).max = max(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).min = min(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).std = std(dataIn(~isnan(dataIn)));
                            else
                                % Store the last time stamp collected from the instrument
                                % as datenum, but store the time only (no date)
                                tmpStats.(strSiteName).Time = dataIn(end)-floor(dataIn(end));
                                % Store also the TimeVector 
                                tmpStats.(strSiteName).TimeVector = currentHHour;
                                % Calculate stats on the sampling rate (how often is data collected)
                                tmpSampleRate = diff(dataIn *24*3600);
                                if ~isempty(tmpSampleRate)
                                    tmpStats.(strSiteName).SampleRate.avg = mean(tmpSampleRate); %#ok<*AGROW> 
                                    tmpStats.(strSiteName).SampleRate.max = max(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.min = min(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.std = std(tmpSampleRate);
                                else
                                    tmpStats.(strSiteName).SampleRate.avg = NaN; 
                                    tmpStats.(strSiteName).SampleRate.max = NaN;
                                    tmpStats.(strSiteName).SampleRate.min = NaN;
                                    tmpStats.(strSiteName).SampleRate.std = NaN;      
                                end
                            end
                        end
                        tmpStats.(strSiteName).numOfSamples = length(dataIn);
                    end
                end
                % Add TimeVector to the main structure
                tmpStats.TimeVector = currentHHour;
                % save half hourly stats using currentHHour (need an
                % end-of-period time stamp). 
                fileName = sprintf(app.outputFileNameHH,datestr(currentHHour,'yyyymmdd'));
                filePathHH = fullfile(app.outputPathHH,fileName);
                % check if the file with the same name already exists there
                if exist(filePathHH,'file')
                    load(filePathHH,'Stats');
                    numRec = length(Stats);
                else
                    numRec = 0;
                end
                numRec = numRec+1;
                % append the new record to the existing ones
                Stats(numRec) = tmpStats;
                % save hhour stats
                save(filePathHH,'Stats');
                % also save the data into database (only the last record
                Stats = tmpStats;
                allSites = {'BB1','BB2','DSM','RBM','HOGG','YOUNG','OHM'};
                for cntSite = 1:length(allSites)
                    currentSite = allSites{cntSite};
                    excludeSites = setdiff(allSites,currentSite);
                    if strcmpi(currentSite,'BB1')
                        siteName = 'BB';
                    else
                        siteName = currentSite;
                    end
                    % append the year to outputPathDB
                    dbasePath = fullfile(app.outputPathDB,datestr(currentHHour,'yyyy'));
                    [~,~,~, ~] = ...
                        db_new_eddy(Stats,[],...
                                    fullfile(dbasePath,siteName,'monitorSites'),...
                                    0,excludeSites,[],NaN); 
                end
            catch ME
                % If an error, skip hhour calculations
                % Display the error message for troubleshooting purposes.
                disp(ME.message);
            end       
        end % function halfHourEvent
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Check which PC is app running on (ENG0x or vinimet) and

            if ~exist('p:\database','dir')
                app.outputPathHF = 'd:/junk/monitorSiteLI7x00/data';
                app.outputPathHH = 'd:/junk/monitorSiteLI7x00/hhour';
                app.outputPathDB = 'd:/junk/monitorSiteLI7x00/database';
                app.delayCalling = 20;      % offset calling sites by 20 seconds to avoid overlap with vinimet
                app.samplingPeriodSec = 60; % faster sampling during app design
            else
                app.outputPathHF = 'P:/Sites/Common/monitorSiteLI7x00/data';
                app.outputPathHH = 'P:/Sites/Common/monitorSiteLI7x00/hhour';
                app.outputPathDB = 'P:/Database';
                app.delayCalling = 0;
                app.samplingPeriodSec = 180;
            end
            %app.Label_15.Text = app.outputPathDB; 

            % Import site names and IP addresses from a YAML file
            app.siteInfo(1).name = 'BB1';
            app.siteInfo(1).IPaddress = "173.181.138.49";            
            app.siteInfo(2).name = 'BB2';
            app.siteInfo(2).IPaddress = "96.1.106.219";                        
            app.siteInfo(3).name = 'DSM';
            app.siteInfo(3).IPaddress = "173.181.139.5";                                    
            app.siteInfo(4).name = 'RBM';
            app.siteInfo(4).IPaddress = "173.181.139.4";                        
            app.siteInfo(5).name = 'HOGG';
            app.siteInfo(5).IPaddress = "68.182.37.103";                        
            app.siteInfo(6).name = 'YOUNG';
            app.siteInfo(6).IPaddress = "68.182.37.116";                        
            app.siteInfo(7).name = 'OHM';
            app.siteInfo(7).IPaddress = "68.179.19.212";            
            
            % Name all Tabs
            for cntSites = 1:length(app.siteInfo)
                % create a new tab
                if cntSites == 1
                    currentTab = app.TabGroup.Children;
                else
                    currentTab = uitab(app.TabGroup,'Title',app.siteInfo(cntSites).name);
                end
                currentTab.Title = app.siteInfo(cntSites).name;
                tabPos = currentTab.Position;
                app.siteInfo(cntSites).textArea1 = uitextarea(currentTab,Position=[tabPos(1:2)+2 350 380],Editable = 'off',Fontname = 'Consolas');
                app.siteInfo(cntSites).textArea1.HorizontalAlignment = 'left';
                app.siteInfo(cntSites).textArea1.Value = [sprintf('%30s = %6.2f\n','co2',555-cntSites) ,sprintf('%30s = %6.2f\n','SignalStrength',100-cntSites/5)];
                app.siteInfo(cntSites).textArea1.FontColor = [0 0 0];

            end
            
            % updated data once to populate the table
            UpdateAllButtonPushed(app)
            
            % stop the timer function if already running
            t = timerfind('Name','siteUpdateTimer');
            if ~isempty(t)
                stop(t)
                delete(t)
            end
            
            % calculate delay time so the sampling time can be aligned to
            % sample at 0 seconds into samplingPeriodSec
            tv = now;
            samplesPerDay = 24*3600/app.samplingPeriodSec;         
            nextPeriod = ceil(tv*samplesPerDay)/samplesPerDay;
            delayTime = round((nextPeriod-tv)*24*3600) + app.delayCalling;  % delay time in seconds
            % create a timer that will update data every
            % 5 minutes (300s). 
            % to speedup debugging, run the timer faster (once every 60 seconds)
            app.timerMain = timer( "timerfcn",@app.mainTimerCallback,...
                                   "StartDelay",delayTime,...
                                   "Period",app.samplingPeriodSec,...
                                   "ExecutionMode",'fixedRate',...
                                   "Name",'siteUpdateTimer');
            % then start the timerMain
            start(app.timerMain)
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)

            % Check if this is a new 30-min period and,
            % if it is, store the collected data (if there is any)
            if app.sampleNum > 0
                % check if this is a new 30min period
                lastHHour = fix(app.siteData(app.sampleNum).TimeVector*48)/48;
                currentHHour = fix(now*48)/48;
                if lastHHour < currentHHour
                    % calculate hhourly stats
                    % calculate stats for all variables for each site
                    % store high frequency and half-hourly values
                    halfHourEvent(app,currentHHour)
                end
                
            end
                
            % There is a new data sample being collected. Increment counter
            app.sampleNum = app.sampleNum + 1;
            app.siteData(app.sampleNum).TimeVector = now;
            app.LastUpdateLabel.Text = datestr(now,31);

            for cntSites = 1:length(app.siteInfo)
                downloadData(app,app.siteInfo(cntSites).name,app.siteInfo(cntSites).IPaddress);
            end
            
        end

        % Close request function: MicrometSiteInstrumentsUIFigure
        function MicrometSiteInstrumentsUIFigureCloseRequest(app, event)
            stop(app.timerMain)
            delete(app.timerMain)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrometSiteInstrumentsUIFigure = uifigure('Visible', 'off');
            app.MicrometSiteInstrumentsUIFigure.Name = 'Micromet Site Instruments';
            app.MicrometSiteInstrumentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrometSiteInstrumentsUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MicrometSiteInstrumentsUIFigure);
            app.Site_1 = uitab(app.TabGroup);
            app.LastdataupdatePanel = uipanel(app.MicrometSiteInstrumentsUIFigure);
            app.LastdataupdatePanel.Title = 'Last data update:';
            app.LastUpdateLabel = uilabel(app.LastdataupdatePanel);
            app.LastUpdateLabel.Text = '';
            app.UpdateAllButton = uibutton(app.MicrometSiteInstrumentsUIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Enable = 'off';
            app.UpdateAllButton.Visible = 'off';
            app.UpdateAllButton.Text = 'Update All';
            app.LICORECMonitorLabel = uilabel(app.MicrometSiteInstrumentsUIFigure);
            app.LICORECMonitorLabel.Text = 'LICOR EC Monitor';
            % Show the figure after all components are created
            app.MicrometSiteInstrumentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMonitorLicorEC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrometSiteInstrumentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrometSiteInstrumentsUIFigure)
        end
    end
end